classdef WEB_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CatlogodePlantasMenu  matlab.ui.container.Menu
        AgregarButton         matlab.ui.control.Button
        ModificarButton       matlab.ui.control.Button
        EliminarButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    properties (Access = public)
         AgregarApp              % Dialog box app
         tabla = []    
         socket
    end
    
    methods (Access = public)
        
        function sendData(app,data)
            fwrite(app.socket, data)
        end
        
        function data = receiveData(app)
            data = fgetl(app.socket);
        end


        function updateTable(app)
            app.sendData('getPlants')
            app.tabla = app.receiveData();
            app.tabla = replace(app.tabla,"u'","");
            app.tabla = replace(app.tabla,"'","");
            app.tabla = replace(app.tabla,"None"," ");
            app.tabla = replace(app.tabla,"[(","");
            app.tabla = replace(app.tabla,")]","");
            app.tabla = split(app.tabla,'), (');
            app.tabla = split(app.tabla,',');
            app.UITable.Data = app.tabla;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all
            
            
            app.socket = tcpip('192.168.1.2', 30000, 'NetworkRole', 'client');
            fopen(app.socket)
            
            app.updateTable()
            %app.tabla = split(app.tabla,'), (')
            
            
            %datos = textscan(app.tabla,'%s','Delimiter','), (')'
            %app.UITable.Data = ;
            
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            app.CatlogodePlantasMenu.Enable = 'off';
            app.AgregarButton.Enable = 'off';
            app.ModificarButton.Enable = 'off';
            app.EliminarButton.Enable = 'off';


            app.AgregarApp = agregar(app);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.socket)
            delete(app)
            
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
            %app.socket
            %dato = cellstr(app.tabla)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CatlogodePlantasMenu = uimenu(app.UIFigure);
            app.CatlogodePlantasMenu.Text = 'Catálogo de Plantas';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.Text = 'Eliminar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'; 'Habilitar'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WEB_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end